classdef unit_converter_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        ConverterLabel       matlab.ui.control.Label
        CategoriesLabel      matlab.ui.control.Label
        ConvertButton        matlab.ui.control.Button
        categoriesBox        matlab.ui.control.ListBox
        Panel_2              matlab.ui.container.Panel
        GridLayout           matlab.ui.container.GridLayout
        ConverttoDropDown    matlab.ui.control.DropDown
        arrow_2              matlab.ui.control.Image
        outputField          matlab.ui.control.NumericEditField
        inputField           matlab.ui.control.NumericEditField
        arrow_1              matlab.ui.control.Image
        ConvertfromDropDown  matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ConvertButton, ConvertfromDropDown, 
        % ...and 1 other component
        function ConvertButtonPushed(app, event)
            conversionInput = app.inputField.Value;

            switch (app.categoriesBox.Value)
                case "Length"
                    switch (app.ConvertfromDropDown.Value)
                        case "meter"
                            switch (app.ConverttoDropDown.Value)
                                case "meter"
                                    conversionOutput = conversionInput;
                                case "kilometer"
                                    conversionOutput = conversionInput / 1000;
                                case "centimeter"
                                    conversionOutput = conversionInput * 100;
                                case "millimeter"
                                    conversionOutput = conversionInput * 1000;
                                case "inch"
                                    conversionOutput = conversionInput * 0.393701;
                                case "foot"
                                    conversionOutput = conversionInput * 3.28084;
                                case "yard"
                                    conversionOutput = conversionInput * 1.09361;
                                case "mile"
                                    conversionOutput = conversionInput * 0.000621371;
                            end
                        case "kilometer"
                            switch (app.ConverttoDropDown.Value)
                                case "meter"
                                    conversionOutput = conversionInput * 1000;
                                case "kilometer"
                                    conversionOutput = conversionInput;
                                case "centimeter"
                                    conversionOutput = conversionInput * 100000;
                                case "inch"
                                    conversionOutput = conversionInput * 39370.1;
                                case "millimeter"
                                    conversionOutput = conversionInput * 1000000;
                                case "foot"
                                    conversionOutput = conversionInput * 3280.84;
                                case "yard"
                                    conversionOutput = conversionInput * 1093.61;
                                case "mile"
                                    conversionOutput = conversionInput * 0.621371;
                            end
                        case "centimeter"
                            switch (app.ConverttoDropDown.Value)
                                case "meter"
                                    conversionOutput = conversionInput * 0.01;
                                case "kilometer"
                                    conversionOutput = conversionInput * 0.00001;
                                case "centimeter"
                                    conversionOutput = conversionInput;
                                case "inch"
                                    conversionOutput = conversionInput * 0.393701;
                                case "millimeter"
                                    conversionOutput = conversionInput * 10;
                                case "foot"
                                    conversionOutput = conversionInput * 0.0328084;
                                case "yard"
                                    conversionOutput = conversionInput * 0.0109361;
                                case "mile"
                                    conversionOutput = conversionInput * 0.00000621371;
                            end
                        case "inch"
                            switch (app.ConverttoDropDown.Value)
                                case "meter"
                                    conversionOutput = conversionInput * 0.0254;
                                case "kilometer"
                                    conversionOutput = conversionInput * 0.0000254;
                                case "centimeter"
                                    conversionOutput = conversionInput * 2.54;
                                case "inch"
                                    conversionOutput = conversionInput;
                                case "millimeter"
                                    conversionOutput = conversionInput * 25.4;
                                case "foot"
                                    conversionOutput = conversionInput * 0.0833333;
                                case "yard"
                                    conversionOutput = conversionInput * 0.0277778;
                                case "mile"
                                    conversionOutput = conversionInput * 0.000015783;
                            end
                        case "millimeter"
                            switch (app.ConverttoDropDown.Value)
                                case "meter"
                                    conversionOutput = conversionInput * 0.001;
                                case "kilometer"
                                    conversionOutput = conversionInput * 0.000001;
                                case "centimeter"
                                    conversionOutput = conversionInput * 0.1;
                                case "inch"
                                    conversionOutput = conversionInput * 0.0393701;
                                case "millimeter"
                                    conversionOutput = conversionInput;
                                case "foot"
                                    conversionOutput = conversionInput * 0.00328084;
                                case "yard"
                                    conversionOutput = conversionInput * 0.0109361;
                                case "mile"
                                    conversionOutput = conversionInput * 0.0000062137;
                            end
                        case "foot"
                            switch (app.ConverttoDropDown.Value)
                                case "meter"
                                    conversionOutput = conversionInput * 0.3048;
                                case "kilometer"
                                    conversionOutput = conversionInput * 0.0003048;
                                case "centimeter"
                                    conversionOutput = conversionInput * 30.48;
                                case "inch"
                                    conversionOutput = conversionInput * 12;
                                case "millimeter"
                                    conversionOutput = conversionInput * 304.8;
                                case "foot"
                                    conversionOutput = conversionInput;
                                case "yard"
                                    conversionOutput = conversionInput * 0.333333;
                                case "mile"
                                    conversionOutput = conversionInput * 0.000189394;
                            end
                        case "yard"
                            switch (app.ConverttoDropDown.Value)
                                case "meter"
                                    conversionOutput = conversionInput * 0.9144;
                                case "kilometer"
                                    conversionOutput = conversionInput * 0.0009144;
                                case "centimeter"
                                    conversionOutput = conversionInput * 91.44;
                                case "inch"
                                    conversionOutput = conversionInput * 36;
                                case "millimeter"
                                    conversionOutput = conversionInput * 914.4;
                                case "foot"
                                    conversionOutput = conversionInput * 3;
                                case "yard"
                                    conversionOutput = conversionInput;
                                case "mile"
                                    conversionOutput = conversionInput * 0.000568182;
                            end
                        case "mile"
                            switch (app.ConverttoDropDown.Value)
                                case "meter"
                                    conversionOutput = conversionInput * 1609.34;
                                case "kilometer"
                                    conversionOutput = conversionInput * 1.60934;
                                case "centimeter"
                                    conversionOutput = conversionInput * 160934;
                                case "inch"
                                    conversionOutput = conversionInput * 63360;
                                case "millimeter"
                                    conversionOutput = conversionInput * 1609340;
                                case "foot"
                                    conversionOutput = conversionInput * 5280;
                                case "yard"
                                    conversionOutput = conversionInput * 1760;
                                case "mile"
                                    conversionOutput = conversionInput;
                            end
                    end
                case "Mass"
                        switch (app.ConvertfromDropDown.Value)
                            case "gram"
                                switch (app.ConverttoDropDown.Value)
                                    case "gram"
                                        conversionOutput = conversionInput;
                                    case "kilogram"
                                        conversionOutput = conversionInput / 1000;
                                    case "milligram"
                                        conversionOutput = conversionInput * 1000;
                                    case "pound"
                                        conversionOutput = conversionInput * 0.00220462;
                                    case "ounce"
                                        conversionOutput = conversionInput * 0.035274;
                                    case "metric ton"
                                        conversionOutput = conversionInput / 1e6;
                                end
                            case "kilogram"
                                switch (app.ConverttoDropDown.Value)
                                    case "gram"
                                        conversionOutput = conversionInput * 1000;
                                    case "kilogram"
                                        conversionOutput = conversionInput;
                                    case "milligram"
                                        conversionOutput = conversionInput * 1e6;
                                    case "pound"
                                        conversionOutput = conversionInput * 2.20462;
                                    case "ounce"
                                        conversionOutput = conversionInput * 35.274;
                                    case "metric ton"
                                        conversionOutput = conversionInput / 1000;
                                end
                            case "milligram"
                                switch (app.ConverttoDropDown.Value)
                                    case "gram"
                                        conversionOutput = conversionInput / 1000;
                                    case "kilogram"
                                        conversionOutput = conversionInput / 1e6;
                                    case "milligram"
                                        conversionOutput = conversionInput;
                                    case "pound"
                                        conversionOutput = conversionInput * 2.2046e-6;
                                    case "ounce"
                                        conversionOutput = conversionInput * 3.5274e-5;
                                    case "metric ton"
                                        conversionOutput = conversionInput / 1e9;
                                end
                            case "pound"
                                switch (app.ConverttoDropDown.Value)
                                    case "gram"
                                        conversionOutput = conversionInput / 0.00220462;
                                    case "kilogram"
                                        conversionOutput = conversionInput / 2.20462;
                                    case "milligram"
                                        conversionOutput = conversionInput / 2.2046e-6;
                                    case "pound"
                                        conversionOutput = conversionInput;
                                    case "ounce"
                                        conversionOutput = conversionInput * 16;
                                    case "metric ton"
                                        conversionOutput = conversionInput / 2204.62;
                                end
                            case "ounce"
                                switch (app.ConverttoDropDown.Value)
                                    case "gram"
                                        conversionOutput = conversionInput / 0.035274;
                                    case "kilogram"
                                        conversionOutput = conversionInput / 35.274;
                                    case "milligram"
                                        conversionOutput = conversionInput / 3.5274e-5;
                                    case "pound"
                                        conversionOutput = conversionInput / 16;
                                    case "ounce"
                                        conversionOutput = conversionInput;
                                    case "metric ton"
                                        conversionOutput = conversionInput / 35274;
                                end
                            case "metric ton"
                                switch (app.ConverttoDropDown.Value)
                                    case "gram"
                                        conversionOutput = conversionInput * 1e6;
                                    case "kilogram"
                                        conversionOutput = conversionInput * 1000;
                                    case "milligram"
                                        conversionOutput = conversionInput * 1e9;
                                    case "pound"
                                        conversionOutput = conversionInput * 2204.62;
                                    case "ounce"
                                        conversionOutput = conversionInput * 35274;
                                    case "metric ton"
                                        conversionOutput = conversionInput;
                                end
                        end
                case "Volume"
                    switch (app.ConvertfromDropDown.Value)
                        case "liter"
                            switch (app.ConverttoDropDown.Value)
                                case "liter"
                                    conversionOutput = conversionInput;
                                case "milliliter"
                                    conversionOutput = conversionInput * 1000;
                                case "cubic meter"
                                    conversionOutput = conversionInput / 1000;
                                case "cubic centimeter"
                                    conversionOutput = conversionInput * 1000;
                                case "gallon"
                                    conversionOutput = conversionInput * 0.264172;
                                case "quart"
                                    conversionOutput = conversionInput * 1.05669;
                                case "pint"
                                    conversionOutput = conversionInput * 2.11338;
                                case "fluid ounce"
                                    conversionOutput = conversionInput * 33.814;
                            end
                        case "milliliter"
                            switch (app.ConverttoDropDown.Value)
                                case "liter"
                                    conversionOutput = conversionInput / 1000;
                                case "milliliter"
                                    conversionOutput = conversionInput;
                                case "cubic meter"
                                    conversionOutput = conversionInput / 1e6;
                                case "cubic centimeter"
                                    conversionOutput = conversionInput;
                                case "gallon"
                                    conversionOutput = conversionInput * 2.64172e-4;
                                case "quart"
                                    conversionOutput = conversionInput * 0.00105669;
                                case "pint"
                                    conversionOutput = conversionInput * 0.00211338;
                                case "fluid ounce"
                                    conversionOutput = conversionInput * 0.033814;
                            end
                        case "cubic meter"
                            switch (app.ConverttoDropDown.Value)
                                case "liter"
                                    conversionOutput = conversionInput * 1000;
                                case "milliliter"
                                    conversionOutput = conversionInput * 1e6;
                                case "cubic meter"
                                    conversionOutput = conversionInput;
                                case "cubic centimeter"
                                    conversionOutput = conversionInput * 1e6;
                                case "gallon"
                                    conversionOutput = conversionInput * 264.172;
                                case "quart"
                                    conversionOutput = conversionInput * 1056.69;
                                case "pint"
                                    conversionOutput = conversionInput * 2113.38;
                                case "fluid ounce"
                                    conversionOutput = conversionInput * 33814;
                            end
                        case "cubic centimeter"
                            switch (app.ConverttoDropDown.Value)
                                case "liter"
                                    conversionOutput = conversionInput / 1000;
                                case "milliliter"
                                    conversionOutput = conversionInput;
                                case "cubic meter"
                                    conversionOutput = conversionInput / 1e6;
                                case "cubic centimeter"
                                    conversionOutput = conversionInput;
                                case "gallon"
                                    conversionOutput = conversionInput * 2.64172e-4;
                                case "quart"
                                    conversionOutput = conversionInput * 0.00105669;
                                case "pint"
                                    conversionOutput = conversionInput * 0.00211338;
                                case "fluid ounce"
                                    conversionOutput = conversionInput * 0.033814;
                            end
                        case "gallon"
                            switch (app.ConverttoDropDown.Value)
                                case "liter"
                                    conversionOutput = conversionInput / 0.264172;
                                case "milliliter"
                                    conversionOutput = conversionInput / 2.64172e-4;
                                case "cubic meter"
                                    conversionOutput = conversionInput / 264.172;
                                case "cubic centimeter"
                                    conversionOutput = conversionInput / 2.64172e-4;
                                case "gallon"
                                    conversionOutput = conversionInput;
                                case "quart"
                                    conversionOutput = conversionInput * 4;
                                case "pint"
                                    conversionOutput = conversionInput * 8;
                                case "fluid ounce"
                                    conversionOutput = conversionInput * 128;
                            end
                        case "quart"
                            switch (app.ConverttoDropDown.Value)
                                case "liter"
                                    conversionOutput = conversionInput / 1.05669;
                                case "milliliter"
                                    conversionOutput = conversionInput / 0.00105669;
                                case "cubic meter"
                                    conversionOutput = conversionInput / 1056.69;
                                case "cubic centimeter"
                                    conversionOutput = conversionInput / 0.00105669;
                                case "gallon"
                                    conversionOutput = conversionInput / 4;
                                case "quart"
                                    conversionOutput = conversionInput;
                                case "pint"
                                    conversionOutput = conversionInput * 2;
                                case "fluid ounce"
                                    conversionOutput = conversionInput * 32;
                            end
                        case "pint"
                            switch (app.ConverttoDropDown.Value)
                                case "liter"
                                    conversionOutput = conversionInput / 2.11338;
                                case "milliliter"
                                    conversionOutput = conversionInput / 0.00211338;
                                case "cubic meter"
                                    conversionOutput = conversionInput / 2113.38;
                                case "cubic centimeter"
                                    conversionOutput = conversionInput / 0.00211338;
                                case "gallon"
                                    conversionOutput = conversionInput / 8;
                                case "quart"
                                    conversionOutput = conversionInput / 2;
                                case "pint"
                                    conversionOutput = conversionInput;
                                case "fluid ounce"
                                    conversionOutput = conversionInput * 16;
                            end
                        case "fluid ounce"
                            switch (app.ConverttoDropDown.Value)
                                case "liter"
                                    conversionOutput = conversionInput / 33.814;
                                case "milliliter"
                                    conversionOutput = conversionInput / 0.033814;
                                case "cubic meter"
                                    conversionOutput = conversionInput / 33814;
                                case "cubic centimeter"
                                    conversionOutput = conversionInput / 0.033814;
                                case "gallon"
                                    conversionOutput = conversionInput / 128;
                                case "quart"
                                    conversionOutput = conversionInput / 32;
                                case "pint"
                                    conversionOutput = conversionInput / 16;
                                case "fluid ounce"
                                    conversionOutput = conversionInput;
                            end
                    end
                case "Area"
                    switch (app.ConvertfromDropDown.Value)
                        case "square meter"
                            switch (app.ConverttoDropDown.Value)
                                case "square meter"
                                    conversionOutput = conversionInput;
                                case "square kilometer"
                                    conversionOutput = conversionInput / 1e6;
                                case "square centimeter"
                                    conversionOutput = conversionInput * 10000;
                                case "square inch"
                                    conversionOutput = conversionInput * 1550;
                                case "square foot"
                                    conversionOutput = conversionInput * 10.7639;
                                case "square yard"
                                    conversionOutput = conversionInput * 1.19599;
                                case "acre"
                                    conversionOutput = conversionInput / 4046.86;
                                case "hectare"
                                    conversionOutput = conversionInput / 10000;
                            end
                        case "square kilometer"
                            switch (app.ConverttoDropDown.Value)
                                case "square meter"
                                    conversionOutput = conversionInput * 1e6;
                                case "square kilometer"
                                    conversionOutput = conversionInput;
                                case "square centimeter"
                                    conversionOutput = conversionInput * 1e10;
                                case "square inch"
                                    conversionOutput = conversionInput * 1.55e9;
                                case "square foot"
                                    conversionOutput = conversionInput * 1.076e7;
                                case "square yard"
                                    conversionOutput = conversionInput * 1.196e6;
                                case "acre"
                                    conversionOutput = conversionInput * 247.105;
                                case "hectare"
                                    conversionOutput = conversionInput * 100;
                            end
                        case "square centimeter"
                            switch (app.ConverttoDropDown.Value)
                                case "square meter"
                                    conversionOutput = conversionInput / 10000;
                                case "square kilometer"
                                    conversionOutput = conversionInput / 1e10;
                                case "square centimeter"
                                    conversionOutput = conversionInput;
                                case "square inch"
                                    conversionOutput = conversionInput * 0.155;
                                case "square foot"
                                    conversionOutput = conversionInput * 0.00107639;
                                case "square yard"
                                    conversionOutput = conversionInput * 0.000119599;
                                case "acre"
                                    conversionOutput = conversionInput / 4.047e7;
                                case "hectare"
                                    conversionOutput = conversionInput / 1e8;
                            end
                        case "square inch"
                            switch (app.ConverttoDropDown.Value)
                                case "square meter"
                                    conversionOutput = conversionInput / 1550;
                                case "square kilometer"
                                    conversionOutput = conversionInput / 1.55e9;
                                case "square centimeter"
                                    conversionOutput = conversionInput / 0.155;
                                case "square inch"
                                    conversionOutput = conversionInput;
                                case "square foot"
                                    conversionOutput = conversionInput / 144;
                                case "square yard"
                                    conversionOutput = conversionInput / 1296;
                                case "acre"
                                    conversionOutput = conversionInput / 6.273e6;
                                case "hectare"
                                    conversionOutput = conversionInput / 1.55e7;
                            end
                        case "square foot"
                            switch (app.ConverttoDropDown.Value)
                                case "square meter"
                                    conversionOutput = conversionInput / 10.7639;
                                case "square kilometer"
                                    conversionOutput = conversionInput / 1.076e7;
                                case "square centimeter"
                                    conversionOutput = conversionInput * 929.03;
                                case "square inch"
                                    conversionOutput = conversionInput * 144;
                                case "square foot"
                                    conversionOutput = conversionInput;
                                case "square yard"
                                    conversionOutput = conversionInput / 9;
                                case "acre"
                                    conversionOutput = conversionInput / 43560;
                                case "hectare"
                                    conversionOutput = conversionInput / 107639;
                            end
                        case "square yard"
                            switch (app.ConverttoDropDown.Value)
                                case "square meter"
                                    conversionOutput = conversionInput / 1.19599;
                                case "square kilometer"
                                    conversionOutput = conversionInput / 1.196e6;
                                case "square centimeter"
                                    conversionOutput = conversionInput * 8361.27;
                                case "square inch"
                                    conversionOutput = conversionInput * 1296;
                                case "square foot"
                                    conversionOutput = conversionInput * 9;
                                case "square yard"
                                    conversionOutput = conversionInput;
                                case "acre"
                                    conversionOutput = conversionInput / 4840;
                                case "hectare"
                                    conversionOutput = conversionInput / 11960;
                            end
                        case "acre"
                            switch (app.ConverttoDropDown.Value)
                                case "square meter"
                                    conversionOutput = conversionInput * 4046.86;
                                case "square kilometer"
                                    conversionOutput = conversionInput * 0.00404686;
                                case "square centimeter"
                                    conversionOutput = conversionInput * 4.047e7;
                                case "square inch"
                                    conversionOutput = conversionInput * 6.273e6;
                                case "square foot"
                                    conversionOutput = conversionInput * 43560;
                                case "square yard"
                                    conversionOutput = conversionInput * 4840;
                                case "acre"
                                    conversionOutput = conversionInput;
                                case "hectare"
                                    conversionOutput = conversionInput * 0.404686;
                            end
                        case "hectare"
                            switch (app.ConverttoDropDown.Value)
                                case "square meter"
                                    conversionOutput = conversionInput * 10000;
                                case "square kilometer"
                                    conversionOutput = conversionInput / 100;
                                case "square centimeter"
                                    conversionOutput = conversionInput * 1e8;
                                case "square inch"
                                    conversionOutput = conversionInput * 1.55e7;
                                case "square foot"
                                    conversionOutput = conversionInput * 107639;
                                case "square yard"
                                    conversionOutput = conversionInput * 11960;
                                case "acre"
                                    conversionOutput = conversionInput * 2.47105;
                                case "hectare"
                                    conversionOutput = conversionInput;
                            end
                    end
                case "Time"
                    switch (app.ConvertfromDropDown.Value)
                        case "second"
                            switch (app.ConverttoDropDown.Value)
                                case "second"
                                    conversionOutput = conversionInput;
                                case "minute"
                                    conversionOutput = conversionInput / 60;
                                case "hour"
                                    conversionOutput = conversionInput / 3600;
                                case "day"
                                    conversionOutput = conversionInput / 86400;
                                case "week"
                                    conversionOutput = conversionInput / 604800;
                                case "month"
                                    conversionOutput = conversionInput / 2.628e6;  % Average month length (30.44 days)
                                case "year"
                                    conversionOutput = conversionInput / 3.154e7;  % Average year length (365.25 days)
                            end
                        case "minute"
                            switch (app.ConverttoDropDown.Value)
                                case "second"
                                    conversionOutput = conversionInput * 60;
                                case "minute"
                                    conversionOutput = conversionInput;
                                case "hour"
                                    conversionOutput = conversionInput / 60;
                                case "day"
                                    conversionOutput = conversionInput / 1440;
                                case "week"
                                    conversionOutput = conversionInput / 10080;
                                case "month"
                                    conversionOutput = conversionInput / 43800;  % Approximate minutes in a month
                                case "year"
                                    conversionOutput = conversionInput / 525600;  % Approximate minutes in a year
                            end
                        case "hour"
                            switch (app.ConverttoDropDown.Value)
                                case "second"
                                    conversionOutput = conversionInput * 3600;
                                case "minute"
                                    conversionOutput = conversionInput * 60;
                                case "hour"
                                    conversionOutput = conversionInput;
                                case "day"
                                    conversionOutput = conversionInput / 24;
                                case "week"
                                    conversionOutput = conversionInput / 168;
                                case "month"
                                    conversionOutput = conversionInput / 730;  % Approximate hours in a month
                                case "year"
                                    conversionOutput = conversionInput / 8760;  % Approximate hours in a year
                            end
                        case "day"
                            switch (app.ConverttoDropDown.Value)
                                case "second"
                                    conversionOutput = conversionInput * 86400;
                                case "minute"
                                    conversionOutput = conversionInput * 1440;
                                case "hour"
                                    conversionOutput = conversionInput * 24;
                                case "day"
                                    conversionOutput = conversionInput;
                                case "week"
                                    conversionOutput = conversionInput / 7;
                                case "month"
                                    conversionOutput = conversionInput / 30.44;  % Average days in a month
                                case "year"
                                    conversionOutput = conversionInput / 365.25;  % Average days in a year
                            end
                        case "week"
                            switch (app.ConverttoDropDown.Value)
                                case "second"
                                    conversionOutput = conversionInput * 604800;
                                case "minute"
                                    conversionOutput = conversionInput * 10080;
                                case "hour"
                                    conversionOutput = conversionInput * 168;
                                case "day"
                                    conversionOutput = conversionInput * 7;
                                case "week"
                                    conversionOutput = conversionInput;
                                case "month"
                                    conversionOutput = conversionInput / 4.345;  % Approximate weeks in a month
                                case "year"
                                    conversionOutput = conversionInput / 52.1775;  % Approximate weeks in a year
                            end
                        case "month"
                            switch (app.ConverttoDropDown.Value)
                                case "second"
                                    conversionOutput = conversionInput * 2.628e6;  % Approximate seconds in a month
                                case "minute"
                                    conversionOutput = conversionInput * 43800;  % Approximate minutes in a month
                                case "hour"
                                    conversionOutput = conversionInput * 730;  % Approximate hours in a month
                                case "day"
                                    conversionOutput = conversionInput * 30.44;  % Average days in a month
                                case "week"
                                    conversionOutput = conversionInput * 4.345;  % Approximate weeks in a month
                                case "month"
                                    conversionOutput = conversionInput;
                                case "year"
                                    conversionOutput = conversionInput / 12;
                            end
                        case "year"
                            switch (app.ConverttoDropDown.Value)
                                case "second"
                                    conversionOutput = conversionInput * 3.154e7;  % Approximate seconds in a year
                                case "minute"
                                    conversionOutput = conversionInput * 525600;  % Approximate minutes in a year
                                case "hour"
                                    conversionOutput = conversionInput * 8760;  % Approximate hours in a year
                                case "day"
                                    conversionOutput = conversionInput * 365.25;  % Average days in a year
                                case "week"
                                    conversionOutput = conversionInput * 52.1775;  % Approximate weeks in a year
                                case "month"
                                    conversionOutput = conversionInput * 12;
                                case "year"
                                    conversionOutput = conversionInput;
                            end
                    end
                case "Temperature"
                    switch (app.ConvertfromDropDown.Value)
                        case "celsius"
                            switch (app.ConverttoDropDown.Value)
                                case "celsius"
                                    conversionOutput = conversionInput;
                                case "fahrenheit"
                                    conversionOutput = (conversionInput * 9/5) + 32;
                                case "kelvin"
                                    conversionOutput = conversionInput + 273.15;
                            end
                        case "fahrenheit"
                            switch (app.ConverttoDropDown.Value)
                                case "celsius"
                                    conversionOutput = (conversionInput - 32) * 5/9;
                                case "fahrenheit"
                                    conversionOutput = conversionInput;
                                case "kelvin"
                                    conversionOutput = (conversionInput - 32) * 5/9 + 273.15;
                            end
                        case "kelvin"
                            switch (app.ConverttoDropDown.Value)
                                case "celsius"
                                    conversionOutput = conversionInput - 273.15;
                                case "fahrenheit"
                                    conversionOutput = (conversionInput - 273.15) * 9/5 + 32;
                                case "kelvin"
                                    conversionOutput = conversionInput;
                            end
                    end
                case "Velocity"
                    switch (app.ConvertfromDropDown.Value)
                        case "meters/second"
                            switch (app.ConverttoDropDown.Value)
                                case "meters/second"
                                    conversionOutput = conversionInput;
                                case "kilometers/hour"
                                    conversionOutput = conversionInput * 3.6;
                                case "mile/hour"
                                    conversionOutput = conversionInput * 2.23694;
                                case "feet/second"
                                    conversionOutput = conversionInput * 3.28084;
                                case "knots"
                                    conversionOutput = conversionInput * 1.94384;
                            end
                        case "kilometers/hour"
                            switch (app.ConverttoDropDown.Value)
                                case "meters/second"
                                    conversionOutput = conversionInput / 3.6;
                                case "kilometers/hour"
                                    conversionOutput = conversionInput;
                                case "mile/hour"
                                    conversionOutput = conversionInput * 0.621371;
                                case "feet/second"
                                    conversionOutput = conversionInput * 0.911344;
                                case "knots"
                                    conversionOutput = conversionInput * 0.539957;
                            end
                        case "mile/hour"
                            switch (app.ConverttoDropDown.Value)
                                case "meters/second"
                                    conversionOutput = conversionInput / 2.23694;
                                case "kilometers/hour"
                                    conversionOutput = conversionInput / 0.621371;
                                case "mile/hour"
                                    conversionOutput = conversionInput;
                                case "feet/second"
                                    conversionOutput = conversionInput * 1.46667;
                                case "knots"
                                    conversionOutput = conversionInput * 0.868976;
                            end
                        case "feet/second"
                            switch (app.ConverttoDropDown.Value)
                                case "meters/second"
                                    conversionOutput = conversionInput / 3.28084;
                                case "kilometers/hour"
                                    conversionOutput = conversionInput / 0.911344;
                                case "mile/hour"
                                    conversionOutput = conversionInput / 1.46667;
                                case "feet/second"
                                    conversionOutput = conversionInput;
                                case "knots"
                                    conversionOutput = conversionInput * 0.592484;
                            end
                        case "knots"
                            switch (app.ConverttoDropDown.Value)
                                case "meters/second"
                                    conversionOutput = conversionInput / 1.94384;
                                case "kilometers/hour"
                                    conversionOutput = conversionInput / 0.539957;
                                case "mile/hour"
                                    conversionOutput = conversionInput / 0.868976;
                                case "feet/second"
                                    conversionOutput = conversionInput / 0.592484;
                                case "knots"
                                    conversionOutput = conversionInput;
                            end
                    end
                case "Force"
                    switch (app.ConvertfromDropDown.Value)
                        case "newton"
                            switch (app.ConverttoDropDown.Value)
                                case "newton"
                                    conversionOutput = conversionInput;
                                case "kilogram-force"
                                    conversionOutput = conversionInput * 0.101972;
                                case "pound-force"
                                    conversionOutput = conversionInput * 0.224809;
                            end
                        case "kilogram-force"
                            switch (app.ConverttoDropDown.Value)
                                case "newton"
                                    conversionOutput = conversionInput / 0.101972;
                                case "kilogram-force"
                                    conversionOutput = conversionInput;
                                case "pound-force"
                                    conversionOutput = conversionInput * 2.20462;
                            end
                        case "pound-force"
                            switch (app.ConverttoDropDown.Value)
                                case "newton"
                                    conversionOutput = conversionInput / 0.224809;
                                case "kilogram-force"
                                    conversionOutput = conversionInput / 2.20462;
                                case "pound-force"
                                    conversionOutput = conversionInput;
                            end
                    end
                case "Energy"
                    switch (app.ConvertfromDropDown.Value)
                        case "joule"
                            switch (app.ConverttoDropDown.Value)
                                case "joule"
                                    conversionOutput = conversionInput;
                                case "kilojoule"
                                    conversionOutput = conversionInput / 1000;
                                case "calorie"
                                    conversionOutput = conversionInput * 0.239006;
                                case "kilocalorie"
                                    conversionOutput = conversionInput * 0.000239006;
                                case "watt-hour"
                                    conversionOutput = conversionInput / 3600;
                                case "kilowatt-hour"
                                    conversionOutput = conversionInput / 3.6e6;
                                case "british thermal unit"
                                    conversionOutput = conversionInput * 0.000947817;
                            end
                        case "kilojoule"
                            switch (app.ConverttoDropDown.Value)
                                case "joule"
                                    conversionOutput = conversionInput * 1000;
                                case "kilojoule"
                                    conversionOutput = conversionInput;
                                case "calorie"
                                    conversionOutput = conversionInput * 239.006;
                                case "kilocalorie"
                                    conversionOutput = conversionInput * 0.239006;
                                case "watt-hour"
                                    conversionOutput = conversionInput / 3600 * 1000;
                                case "kilowatt-hour"
                                    conversionOutput = conversionInput / 3.6e6 * 1000;
                                case "british thermal unit"
                                    conversionOutput = conversionInput * 947.817;
                            end
                        case "calorie"
                            switch (app.ConverttoDropDown.Value)
                                case "joule"
                                    conversionOutput = conversionInput / 0.239006;
                                case "kilojoule"
                                    conversionOutput = conversionInput / 239.006;
                                case "calorie"
                                    conversionOutput = conversionInput;
                                case "kilocalorie"
                                    conversionOutput = conversionInput * 0.001;
                                case "watt-hour"
                                    conversionOutput = conversionInput * 0.00116222;
                                case "kilowatt-hour"
                                    conversionOutput = conversionInput * 1.16222e-6;
                                case "british thermal unit"
                                    conversionOutput = conversionInput * 0.00396832;
                            end
                        case "kilocalorie"
                            switch (app.ConverttoDropDown.Value)
                                case "joule"
                                    conversionOutput = conversionInput / 0.000239006;
                                case "kilojoule"
                                    conversionOutput = conversionInput / 0.239006;
                                case "calorie"
                                    conversionOutput = conversionInput * 1000;
                                case "kilocalorie"
                                    conversionOutput = conversionInput;
                                case "watt-hour"
                                    conversionOutput = conversionInput * 1.16222;
                                case "kilowatt-hour"
                                    conversionOutput = conversionInput * 1.16222e-3;
                                case "british thermal unit"
                                    conversionOutput = conversionInput * 3.96832;
                            end
                        case "watt-hour"
                            switch (app.ConverttoDropDown.Value)
                                case "joule"
                                    conversionOutput = conversionInput * 3600;
                                case "kilojoule"
                                    conversionOutput = conversionInput * 3.6;
                                case "calorie"
                                    conversionOutput = conversionInput * 859.845;
                                case "kilocalorie"
                                    conversionOutput = conversionInput * 0.859845;
                                case "watt-hour"
                                    conversionOutput = conversionInput;
                                case "kilowatt-hour"
                                    conversionOutput = conversionInput / 1000;
                                case "british thermal unit"
                                    conversionOutput = conversionInput * 3.412;
                            end
                        case "kilowatt-hour"
                            switch (app.ConverttoDropDown.Value)
                                case "joule"
                                    conversionOutput = conversionInput * 3.6e6;
                                case "kilojoule"
                                    conversionOutput = conversionInput * 3600;
                                case "calorie"
                                    conversionOutput = conversionInput * 861420;
                                case "kilocalorie"
                                    conversionOutput = conversionInput * 861.420;
                                case "watt-hour"
                                    conversionOutput = conversionInput * 1000;
                                case "kilowatt-hour"
                                    conversionOutput = conversionInput;
                                case "british thermal unit"
                                    conversionOutput = conversionInput * 3412.14;
                            end
                        case "british thermal unit"
                            switch (app.ConverttoDropDown.Value)
                                case "joule"
                                    conversionOutput = conversionInput / 0.000947817;
                                case "kilojoule"
                                    conversionOutput = conversionInput / 947.817;
                                case "calorie"
                                    conversionOutput = conversionInput / 0.00396832;
                                case "kilocalorie"
                                    conversionOutput = conversionInput / 3.96832;
                                case "watt-hour"
                                    conversionOutput = conversionInput / 3.412;
                                case "kilowatt-hour"
                                    conversionOutput = conversionInput / 3412.14;
                                case "british thermal unit"
                                    conversionOutput = conversionInput;
                            end
                    end
                case "Power"
                    switch (app.ConvertfromDropDown.Value)
                        case "watt"
                            switch (app.ConverttoDropDown.Value)
                                case "watt"
                                    conversionOutput = conversionInput;
                                case "kilowatt"
                                    conversionOutput = conversionInput / 1000;
                                case "horsepower"
                                    conversionOutput = conversionInput * 0.00134102;
                            end
                        case "kilowatt"
                            switch (app.ConverttoDropDown.Value)
                                case "watt"
                                    conversionOutput = conversionInput * 1000;
                                case "kilowatt"
                                    conversionOutput = conversionInput;
                                case "horsepower"
                                    conversionOutput = conversionInput * 1.34102;
                            end
                        case "horsepower"
                            switch (app.ConverttoDropDown.Value)
                                case "watt"
                                    conversionOutput = conversionInput / 0.00134102;
                                case "kilowatt"
                                    conversionOutput = conversionInput / 1.34102;
                                case "horsepower"
                                    conversionOutput = conversionInput;
                            end
                    end
                case "Pressure"
                    switch (app.ConvertfromDropDown.Value)
                        case "pascal"
                            switch (app.ConverttoDropDown.Value)
                                case "pascal"
                                    conversionOutput = conversionInput;
                                case "kilopascal"
                                    conversionOutput = conversionInput / 1000;
                                case "bar"
                                    conversionOutput = conversionInput / 100000;
                                case "atmosphere"
                                    conversionOutput = conversionInput / 101325;
                                case "pound/square inch"
                                    conversionOutput = conversionInput * 0.000145038;
                                case "torr"
                                    conversionOutput = conversionInput * 0.00750062;
                            end
                        case "kilopascal"
                            switch (app.ConverttoDropDown.Value)
                                case "pascal"
                                    conversionOutput = conversionInput * 1000;
                                case "kilopascal"
                                    conversionOutput = conversionInput;
                                case "bar"
                                    conversionOutput = conversionInput / 100;
                                case "atmosphere"
                                    conversionOutput = conversionInput / 101.325;
                                case "pound/square inch"
                                    conversionOutput = conversionInput * 0.145038;
                                case "torr"
                                    conversionOutput = conversionInput * 7.50062;
                            end
                        case "bar"
                            switch (app.ConverttoDropDown.Value)
                                case "pascal"
                                    conversionOutput = conversionInput * 100000;
                                case "kilopascal"
                                    conversionOutput = conversionInput * 100;
                                case "bar"
                                    conversionOutput = conversionInput;
                                case "atmosphere"
                                    conversionOutput = conversionInput / 1.01325;
                                case "pound/square inch"
                                    conversionOutput = conversionInput * 14.5038;
                                case "torr"
                                    conversionOutput = conversionInput * 750.062;
                            end
                        case "atmosphere"
                            switch (app.ConverttoDropDown.Value)
                                case "pascal"
                                    conversionOutput = conversionInput * 101325;
                                case "kilopascal"
                                    conversionOutput = conversionInput * 101.325;
                                case "bar"
                                    conversionOutput = conversionInput * 1.01325;
                                case "atmosphere"
                                    conversionOutput = conversionInput;
                                case "pound/square inch"
                                    conversionOutput = conversionInput * 14.696;
                                case "torr"
                                    conversionOutput = conversionInput * 760;
                            end
                        case "pound/square inch"
                            switch (app.ConverttoDropDown.Value)
                                case "pascal"
                                    conversionOutput = conversionInput / 0.000145038;
                                case "kilopascal"
                                    conversionOutput = conversionInput / 0.145038;
                                case "bar"
                                    conversionOutput = conversionInput / 14.5038;
                                case "atmosphere"
                                    conversionOutput = conversionInput / 14.696;
                                case "pound/square inch"
                                    conversionOutput = conversionInput;
                                case "torr"
                                    conversionOutput = conversionInput * 51.715;
                            end
                        case "torr"
                            switch (app.ConverttoDropDown.Value)
                                case "pascal"
                                    conversionOutput = conversionInput / 0.00750062;
                                case "kilopascal"
                                    conversionOutput = conversionInput / 7.50062;
                                case "bar"
                                    conversionOutput = conversionInput / 750.062;
                                case "atmosphere"
                                    conversionOutput = conversionInput / 760;
                                case "pound/square inch"
                                    conversionOutput = conversionInput / 51.715;
                                case "torr"
                                    conversionOutput = conversionInput;
                            end
                    end

            end

            app.outputField.Value = conversionOutput;
        end

        % Value changed function: categoriesBox
        function categoriesBoxValueChanged(app, event)
            app.outputField.Value = 0;
            app.inputField.Value = 0;
            
            selectedCategory = app.categoriesBox.Value;
            switch (selectedCategory)
                case "Length"
                    units = {'meter' 'kilometer' 'centimeter' 'millimeter' 'inch' 'foot' 'yard' 'mile'};   
                case "Mass"
                    units = {'gram' 'kilogram' 'milligram' 'pound' 'ounce' 'metric ton'};
                case 'Volume'
                    units = {'liter', 'milliliter' 'cubic meter' 'cubic centimeter' 'gallon' 'quart' 'pint' 'fluid ounce'};
                case 'Area'
                    units = {'square meter' 'square kilometer' 'square centimeter' 'square inch' 'square foot' 'square yard' 'acre' 'hectare'};
                case 'Time'
                    units = {'second' 'minute' 'hour' 'day' 'week' 'month' 'year'};
                case 'Temperature'
                    units = {'celsius' 'fahrenheit' 'kelvin'};
                case 'Velocity'
                    units = {'meters/second' 'kilometers/hour' 'mile/hour' 'feet/second' 'knots'};
                case 'Force'
                    units = {'newton' 'kilogram-force' 'pound-force'};
                case 'Energy'
                    units = {'joule' 'kilojoule' 'calorie' 'kilocalorie' 'watt-hour' 'kilowatt-hour' 'british thermal unit'};
                case 'Power'
                    units = {'watt' 'kilowatt' 'horsepower'};
                case 'Pressure'
                    units = {'pascal' 'kilopascal' 'bar' 'atmosphere' 'pound/square inch' 'torr'};
            end
            
            app.ConvertfromDropDown.Items = units;
            app.ConverttoDropDown.Items = units;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.GridLayout = uigridlayout(app.Panel_2);
            app.ConvertfromDropDown = uidropdown(app.GridLayout);
            app.ConvertfromDropDown.Items = {'meter', 'kilometer', 'centimeter', 'millimeter', 'inch', 'foot', 'yard'};
            app.ConvertfromDropDown.ValueChangedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertfromDropDown.Value = 'meter';
            app.arrow_1 = uiimage(app.GridLayout);
            app.arrow_1.ImageSource = fullfile(pathToMLAPP, 'arrow.png');
            app.inputField = uieditfield(app.GridLayout, 'numeric');
            app.outputField = uieditfield(app.GridLayout, 'numeric');
            app.outputField.Editable = 'off';
            app.arrow_2 = uiimage(app.GridLayout);
            app.arrow_2.ImageSource = fullfile(pathToMLAPP, 'arrow.png');
            app.ConverttoDropDown = uidropdown(app.GridLayout);
            app.ConverttoDropDown.Items = {'meter', 'kilometer', 'centimeter', 'millimeter', 'inch', 'foot', 'yard', 'mile'};
            app.ConverttoDropDown.ValueChangedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConverttoDropDown.Value = 'meter';
            app.categoriesBox = uilistbox(app.Panel);
            app.categoriesBox.Items = {'Length', 'Mass', 'Volume', 'Area', 'Time', 'Temperature', 'Velocity', 'Force', 'Energy', 'Power', 'Pressure'};
            app.categoriesBox.ValueChangedFcn = createCallbackFcn(app, @categoriesBoxValueChanged, true);
            app.categoriesBox.Value = 'Length';
            app.ConvertButton = uibutton(app.Panel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.CategoriesLabel = uilabel(app.Panel);
            app.CategoriesLabel.Text = 'Categories:';
            app.ConverterLabel = uilabel(app.Panel);
            app.ConverterLabel.Text = ' Converter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unit_converter_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end